classdef BreastCancer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        DatabaseConnectionTab          matlab.ui.container.Tab
        ConnectButton                  matlab.ui.control.Button
        StatusLabel                    matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        LoginButton                    matlab.ui.control.Button
        DataAppLabel                   matlab.ui.control.Label
        PasswordEditFieldLabel         matlab.ui.control.Label
        PasswordEditField              matlab.ui.control.EditField
        UsernameEditFieldLabel         matlab.ui.control.Label
        UsernameEditField              matlab.ui.control.EditField
        DatabaseEditFieldLabel         matlab.ui.control.Label
        DatabaseEditField              matlab.ui.control.EditField
        ReviewLabel                    matlab.ui.control.Label
        GenderAgeTab                   matlab.ui.container.Tab
        Switch                         matlab.ui.control.Switch
        Label_3                        matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        BinaryClassifiersTab           matlab.ui.container.Tab
        BinaryClassifiersLabel         matlab.ui.control.Label
        TabGroup2                      matlab.ui.container.TabGroup
        SelectionTab                   matlab.ui.container.Tab
        ButtonGroup                    matlab.ui.container.ButtonGroup
        ANNButton                      matlab.ui.control.RadioButton
        LogisticRegressionButton       matlab.ui.control.RadioButton
        SVMButton                      matlab.ui.control.RadioButton
        OversamplingButtonGroup        matlab.ui.container.ButtonGroup
        DisabledButton                 matlab.ui.control.ToggleButton
        TrainsetonlyButton             matlab.ui.control.ToggleButton
        WholesetButton                 matlab.ui.control.ToggleButton
        FeaturesSelectionButtonGroup   matlab.ui.container.ButtonGroup
        DisabledButton_2               matlab.ui.control.ToggleButton
        BenjaminiHochbergButton        matlab.ui.control.ToggleButton
        BonferroniButton               matlab.ui.control.ToggleButton
        FeaturesReductionButtonGroup   matlab.ui.container.ButtonGroup
        DisabledButton_3               matlab.ui.control.ToggleButton
        LDAButton                      matlab.ui.control.ToggleButton
        PCAButton                      matlab.ui.control.ToggleButton
        LDAPCAButton                   matlab.ui.control.ToggleButton
        GOButton                       matlab.ui.control.Button
        PathLabel                      matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        VariancePCAEditFieldLabel      matlab.ui.control.Label
        VariancePCAEditField           matlab.ui.control.NumericEditField
        ThresholdWeightEditFieldLabel  matlab.ui.control.Label
        ThresholdWeightEditField       matlab.ui.control.NumericEditField
        ResultsTab                     matlab.ui.container.Tab
        Label_2                        matlab.ui.control.Label
        ROCCurveTab                    matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        connessione
        data_table
        path
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            app.connessione=database('Prova1', app.UsernameEditField.Value, app.PasswordEditField.Value);
            if isopen(app.connessione)==1
                app.Lamp.Color=[0 1 0];
                app.StatusLabel.Text='Status: Connessione Riuscita';
                
            else
                app.Lamp.Color=[1 0 0];
                app.StatusLabel.Text=['Status: ' app.connessione.Message];
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            query1=['use ' app.DatabaseEditField.Value ';'];
            try
                execute(app.connessione, query1);
                query2=['SELECT distinct disease_type FROM ' app.DatabaseEditField.Value '.subjects; '];
                [result,~]=fetch(app.connessione, query2);
                app.ReviewLabel.Text=[sprintf('Review: \n%s', result.disease_type{1,1})];
                for i=2:size(result,1)
                    app.ReviewLabel.Text=[app.ReviewLabel.Text,sprintf('\n%s', result.disease_type{i,1})];
                    
                end
            catch e
                app.ReviewLabel.Text=['Errore nell apertura del database \n' e.message];
            end
        end

        % Value changed function: Switch
        function Age_switch(app, event)
            value = app.Switch.Value;
            connection=app.connessione;
            query=['select age_at_diagnosis, tumor_stage, case_id FROM project_breast_cancer.diagnoses;'];
            try
                data_table=fetch(connection, query);
            catch
            end
            for i=1:size(data_table,1)
                try
                    query_gender=['select gender from project_breast_cancer.demographic where case_id ="',data_table.case_id{i},'"'];
                    data_gender=fetch(connection, query_gender);
                    data_table{i,4}=data_gender{1,1};
                catch e
                    app.Label_3.Text=[sprintf('Errore: %s',e)];
                end
            end
            if strcmp(value, 'Age')
                under_50=zeros(size(data_table,1),1);
                between_50_65=zeros(size(data_table,1),1);
                over_65=zeros(size(data_table,1),1);
                for i=1:size(data_table,1)
                    if data_table.age_at_diagnosis(i) < 18250
                        under_50(i)=1;
                    elseif data_table.age_at_diagnosis(i) > 23725
                        over_65(i)=1;
                    else
                        between_50_65(i)=1;
                    end
                end
                
                under_50=logical(under_50);
                between_50_65=logical(between_50_65);
                over_65=logical(over_65);
                under=struct('age',{data_table.age_at_diagnosis(under_50,:)},'stage',{data_table.tumor_stage(under_50,:)},'gender',{data_table.Var4(under_50,:)});
                over=struct('age',{data_table.age_at_diagnosis(over_65,:)},'stage',{data_table.tumor_stage(over_65,:)},'gender',{data_table.Var4(over_65,:)});
                between=struct('age',{data_table.age_at_diagnosis(between_50_65,:)},'stage',{data_table.tumor_stage(between_50_65,:)},'gender',{data_table.Var4(between_50_65,:)});
                stage_count_under=zeros(1,4);
                stage_count_over=zeros(1,4);
                stage_count_between=zeros(1,4);
                
                stage_count_under=stagecount(under.age, under);
                stage_count_over=stagecount(over.age, over);
                stage_count_between=stagecount(between.age, between);
                
                
                x=categorical({'stage i','stage ii', 'stage iii', 'stage iv'});
                y=[stage_count_under(1),stage_count_between(1),stage_count_over(1);stage_count_under(2),stage_count_between(2),stage_count_over(2);stage_count_under(3),stage_count_between(3),stage_count_over(3);stage_count_under(4),stage_count_between(4),stage_count_over(4)];


                bar(app.UIAxes,x,y)
                legend(app.UIAxes,'<50','50-65','>65')
            elseif strcmp(value, 'Gender')
                female=zeros(size(data_table,1),1);
                male=zeros(size(data_table,1),1);
                for i=1:size(data_table,1)
                    if strcmp(data_table.Var4{i},'female')
                        female(i)=1;
                    else
                        male(i)=1;
                    end
                end
                %%
                female=logical(female);
                male=logical(male);
                female_info=struct('stage',{data_table.tumor_stage(female,:)},'gender',{data_table.Var4(female,:)});
                male_info=struct('stage',{data_table.tumor_stage(male,:)},'gender',{data_table.Var4(male,:)});
                
                stage_count_female=zeros(1,4);
                stage_count_male=zeros(1,4);
                
                %%
                stage_count_female=stagecount(female_info.gender,female_info);
                stage_count_male=stagecount(male_info.gender, male_info);
                
                %% barplot
                
                x=categorical({'stage i','stage ii', 'stage iii', 'stage iv'});
                y=[stage_count_female(1),stage_count_male(1);stage_count_female(2),stage_count_male(2);stage_count_female(3),stage_count_male(3);stage_count_female(4),stage_count_male(4)];
                
                bar(app.UIAxes,x,y)
                legend(app.UIAxes,'Female','Male')
                
            end
            
            function stage_count=stagecount(sizestruct,struct)
                stage_count=zeros(1,4);
                for i=1:size(sizestruct,1)
                    if contains(['stage i','stage ia','stage ib'],struct.stage{i,1})
                        stage_count(1,1)=stage_count(1,1)+1;
                    elseif contains(['stage ii','stage iia','stage iib'],struct.stage{i,1})
                        stage_count(1,2)=stage_count(1,2)+1;
                    elseif contains(['stage iii','stage iiia','stage iiib','stage iiic'],struct.stage{i,1})
                        stage_count(1,3)=stage_count(1,3)+1;
                    elseif contains(['stage iv'],struct.stage{i,1})
                        stage_count(1,4)=stage_count(1,4)+1;
                    end
                end
                
            end
            
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if app.OversamplingButtonGroup.SelectedObject==app.DisabledButton
                do_oversampling_all=false;
                do_oversampling_t=false;
            elseif app.OversamplingButtonGroup.SelectedObject==app.TrainsetonlyButton
                do_oversampling_t=true;
                do_oversampling_all=false;
            else
                do_oversampling_t=false;
                do_oversampling_all=true;
            end
            if app.FeaturesSelectionButtonGroup.SelectedObject == app.DisabledButton_2
                fs_bh=false;
                fs_bonferroni=false;
            elseif app.FeaturesSelectionButtonGroup.SelectedObject == app.BenjaminiHochbergButton
                fs_bh=true;
                fs_bonferroni=false;
            else
                fs_bh=false;
                fs_bonferroni=true;
            end
            if app.FeaturesReductionButtonGroup.SelectedObject == app.DisabledButton_3
                do_lda=false;
                do_pca=false;
            elseif app.FeaturesReductionButtonGroup.SelectedObject == app.LDAButton
                do_lda=true;
                do_pca=false;
            elseif app.FeaturesReductionButtonGroup.SelectedObject ==app.PCAButton
                do_lda=false;
                do_pca=true;
            else
                do_lda=true;
                do_pca=true;
            end
            [file,app.path]=uigetfile('*.mat');
            g=load(fullfile(app.path,file));
            app.Label.Text=fullfile(app.path,file);
            clinicalData=g.clinicalData;
            genData=g.genData;
            app.Label_2.Text="";
            % Count Features and Samples
            nFeatures = size(genData, 1);
            nPatients = size(genData, 2);
            app.Label_2.Text=[app.Label_2.Text,sprintf('\nCounting %d features and %d samples',nFeatures,nPatients)];
            
            y=zeros(size(clinicalData,1),1)';
            labels_low_mask = zeros(nPatients,1);
            labels_high_mask = zeros(nPatients,1);
            labels_low = [];
            labels_high = [];
            label_mask=[];
            for i = 1:nPatients
                if clinicalData{i,1}{1} == "low"
                    labels_low = [labels_low, i];
                    label_mask(i)=0;
                    labels_low_mask(i) = 1;
                else
                    y(i)=1;
                    labels_high = [labels_high, i];
                    labels_high_mask(i) = 1;
                    label_mask(i)=1;
                end
            end
            nLows = numel(labels_low);
            nHighs = numel(labels_high);
            
            
            % normalizzazione con media geometrica
            genDataArray = table2array(genData);
            counts = genDataArray; %counts=x
            
            pseudoRefSample = geomean(counts,2);
            nz = pseudoRefSample > 0;
            ratios = bsxfun(@rdivide,counts(nz,:),pseudoRefSample(nz));
            sizeFactors = median(ratios,1);
            
            % transform to common scale
            normCounts = bsxfun(@rdivide,counts,sizeFactors);
            
            % SMOTE
            if do_oversampling_all
                [x_new,t_new]=oversampling(normCounts',label_mask',do_oversampling_all);
                labels_low=[];
                labels_high=[];
                for i = 1:size(x_new,1)
                    label = t_new(i,1);
                    if label == 0
                        labels_low(end+1) =i;
                    else
                        labels_high(end+1)=i;
                    end
                end
                normCounts=x_new';
                label_mask=t_new';
            else
                x=normCounts;
            end
            
            % FEATURE SELECTION
            % Constant Variance Link
            if fs_bh || fs_bonferroni
                if do_oversampling_all
                    x=normCounts;
                else
                    x=normCounts';
                end
                tConstant = nbintest(x(:,labels_low),x(:,labels_high),...
                    'VarianceLink','Constant');
                %h = plotVarianceLink(tConstant, 'Compare', 0);
                %h(1).Title.String = 'Variance Link on Low Samples';
                %h(2).Title.String = 'Variance Link on High Samples';
                p_original = tConstant.pValue;
                alpha = 0.05;
                
                % FDR Benjamini-Hochberg
                % Function 'fdr_BH' computes the Benjamini-Hochberg correction of the     %
                % False Discovery Rate for multiple comparisons. The adjusted p-values of %
                % Gordon Smyth are also provided.
                
                if fs_bh
                    [c_pvalues, c_alpha, bh] = fdr_BH(p_original, alpha);
                    x_bh=x(bh,:);
                    app.Label_2.Text=[app.Label_2.Text,sprintf('\nBenjamini-hochberg correction selects %d features',sum(c_pvalues < alpha))];
                end
                
                % FWER Bonferroni
                % Function 'fwer_bonf' computes the Bonferroni correction of Family-Wise  %
                % Error Rate for multiple comparisons.
                
                if fs_bonferroni
                    [c_pvalues, c_alpha, fs_bonf] = fwer_bonf(p_original, alpha);
                    x_bonf=x(fs_bonf,:);
                    
                    app.Label_2.Text=[app.Label_2.Text,sprintf('\n\nBonferroni correction selects %d features',sum(c_pvalues < alpha))];
                    %numel(c_pvalues)
                end
            end
            % ----------------------CROSS VALIDATION------------------------
            %rng('default') % For reproducibility
            if fs_bonferroni
                x=x_bonf';
            elseif fs_bh
                x=x_bh';
            else
                x=normCounts';
            end
            t=label_mask';
            k=10;
            AUC_cv=[]; roc_train={}; roc_test={};accuracy=[];precision=[];recall=[];fscore=[];
            tn=[];fp=[];fn=[];tp=[];
            cv = cvpartition(length(label_mask),'KFold',k)
            for i=1:k
                cv_test=cv.test(i);
                x_train=x(not(cv_test),:)';
                x_test=x(cv_test,:)';
                t_train=[]; t_test=[];
                for j=1:length(cv_test)
                    if cv_test(j) == 0
                        t_train(end+1)=t(j,:);
                    else
                        t_test(end+1)=t(j,:);
                    end
                end
                
                if do_oversampling_t
                    [x_train_os,t_train_os]=oversampling(x_train',t_train',do_oversampling_all);
                    x_train=x_train_os'; t_train=t_train_os';
                end
                %LDA
                
                if do_lda
                    [x_train_new,x_test_new]=lda_fun(x_train',x_test',t_train,i);
                    x_train=x_train_new; x_test=x_test_new;
                end
                %PCA
                
                if do_pca && do_lda
                    [x_train_new,x_test_new]=pca_fun(x_train,x_test,i);
                    x_train=x_train_new; x_test=x_test_new;
                elseif do_pca
                    [x_train_new,x_test_new]=pca_fun(x_train',x_test',i);
                    x_train=x_train_new; x_test=x_test_new;
                elseif do_lda
                    x_train=x_train'; x_test=x_test';
                end
                if selectedButton == app.ANNButton
                    %%ANN
                    hiddenLayerSize = [10 10];
                    
                    trainFcn = 'traingdx';
                    
                    costFunction = 'crossentropy';
                    epochs = 200;
                    lastLayerActivationFunction = 'logsig';
                    
                    net = patternnet(hiddenLayerSize, trainFcn);
                    net.trainParam.epochs = epochs;
                    net.performFcn = costFunction;
                    net.layers{end}.transferFcn = lastLayerActivationFunction;
                    
                    net.divideFcn = 'dividerand';
                    net.divideParam.trainRatio = 100/100;
                    net.divideParam.valRatio   = 0/100;
                    net.divideParam.testRatio  = 0/100;
                    
                    
                    net = configure(net,x_train,t_train);
                    net=init(net);
                                        
                    [net,tr] = train(net,x_train,t_train);
                    
                    y_train = net(x_train);
                    y_test = net(x_test);
                    
                    % AUC
                    [X1,Y1,T1,AUC1]=perfcurve(t_train,y_train,1);
                    roc_train{i,1}=X1; roc_train{i,2}=Y1;
                    
                    [X,Y,T,AUC] = perfcurve(t_test,y_test,1);
                    roc_test{i,1}=X; roc_test{i,2}=Y;
                    AUC_cv(i)=AUC;
                    y_pred_train = double( y_train > 0.5 );
                    y_pred_test = double ( y_test > 0.5 );
                    
                elseif selectedButton == app.LogisticRegressionButton
                    LCModel = fitclinear(x_train',t_train,'Learner','logistic');
                                        
                    % Validazione
                    [y_pred_train, score_train_pred] = LCModel.predict(x_train');  %x_train e test vogliono ' se pca e lda sono diversi
                    [y_pred_test, score_test_pred] = LCModel.predict(x_test');%check'
                    
                    
                    % AUC
                    [X1,Y1,T1,AUC1]=perfcurve(t_train,score_train_pred(:,2),1);
                    roc_train{i,1}=X1; roc_train{i,2}=Y1;
                    
                    [X,Y,T,AUC] = perfcurve(t_test,score_test_pred(:,2),1);
                    roc_test{i,1}=X; roc_test{i,2}=Y;
                    AUC_cv(i)=AUC;
                else
                    SVMModel = fitcsvm(x_train',t_train); %x_train e test vogliono ' se pca and lda sono vere
                    
                    % Validazione
                    [y_pred_train, score_train_pred] = SVMModel.predict(x_train');  %x_train e test vogliono ' se pca and lda sono vere
                    [y_pred_test, score_test_pred] = SVMModel.predict(x_test');
                    
                    % AUC
                    [X1,Y1,T1,AUC1]=perfcurve(t_train,score_train_pred(:,2),1);
                    roc_train{i,1}=X1; roc_train{i,2}=Y1;
                    
                    [X,Y,T,AUC] = perfcurve(t_test,score_test_pred(:,2),1);
                    roc_test{i,1}=X; roc_test{i,2}=Y;
                    AUC_cv(i)=AUC;
                end
                
                % calcparam train
                [C_train, G] = confusionmat(t_train,y_pred_train);
                [accuracy(i,1),precision(i,1),recall(i,1),fscore(i,1)]=calcparam(C_train);
                % calcparam test
                C_test = confusionmat(t_test,y_pred_test);
                [accuracy(i,2),precision(i,2),recall(i,2),fscore(i,2)]=calcparam(C_test);
                
                tn(i,1)= C_train(1,1);tn(i,2)= C_test(1,1);
                fp(i,1)= C_train(1,2);fp(i,2)= C_test(1,2);
                fn(i,1)= C_train(2,1);fn(i,2)= C_test(2,1);
                tp(i,1)= C_train(2,2);tp(i,2)= C_test(2,2);
               
            end
            if do_lda && do_pca
                app.Label_2.Text=[app.Label_2.Text,sprintf('\nAfter LDA&PCA and %d training, we obtain a mean AUC equal to %.4f\n',k,mean(AUC_cv))];
            elseif do_lda
                app.Label_2.Text=[app.Label_2.Text,sprintf('\nAfter LDA and %d training, we obtain a mean AUC equal to %.4f\n',k,mean(AUC_cv))];
            elseif do_pca
                app.Label_2.Text=[app.Label_2.Text,sprintf('\nAfter PCA and %d training, we obtain a mean AUC equal to %.4f\n',k,mean(AUC_cv))];
            else
                app.Label_2.Text=[app.Label_2.Text,sprintf('\nAfter %d training and no PCA or LDA, we obtain a mean AUC equal to %.4f\n',k,mean(AUC_cv))];
            end
            
            app.Label_2.Text=[app.Label_2.Text,sprintf(['\n\n\tTRAIN\nAccuracy: %.3f \tPrecision: %.3f \tRecall: %.3f ...' ...
                '\nFScore: %.3f '],mean(accuracy(:,1)),mean(precision(:,1)),mean(recall(:,1)),mean(fscore(:,1)))];
            app.Label_2.Text=[app.Label_2.Text,sprintf(['\n\n\tTEST\nAccuracy: %.3f \tPrecision: %.3f \tRecall: %.3f ...' ...
                '\nFScore: %.3f '],mean(accuracy(:,2)),mean(precision(:,2)),mean(recall(:,2)),mean(fscore(:,2)))];
            C_train_mean=10*[mean(tn(:,1)),mean(fp(:,1));mean(fn(:,1)),mean(tp(:,1))];
            C_test_mean=10*[mean(tn(:,2)),mean(fp(:,2));mean(fn(:,2)),mean(tp(:,2))];
            app.Label_2.Text=[app.Label_2.Text,sprintf('\nTrain Confusion Matrix')];
            app.Label_2.Text=[app.Label_2.Text,sprintf('\n%.1f\t\t%.1f',C_train_mean(2,2),C_train_mean(1,2))];
            app.Label_2.Text=[app.Label_2.Text,sprintf('\n%.1f\t\t%.1f',C_train_mean(2,1),C_train_mean(1,1))];
            app.Label_2.Text=[app.Label_2.Text,sprintf('\nTest Confusion Matrix')];
            app.Label_2.Text=[app.Label_2.Text,sprintf('\n%.1f\t\t%.1f',C_test_mean(2,2),C_test_mean(1,2))];
            app.Label_2.Text=[app.Label_2.Text,sprintf('\n%.1f\t\t%.1f',C_test_mean(2,1),C_test_mean(1,1))];
           
            
            %ROC CURVES TRAIN
            for i=1:k
                X=roc_train{i,1};
                Y=roc_train{i,2};
                plot(app.UIAxes2,X,Y)
                app.UIAxes2.Title.String='TRAIN ROC';
                app.UIAxes2.XLabel.String='FPR';
                app.UIAxes2.YLabel.String='TPR'
                refline(app.UIAxes2,1,0);
                %hold on
                hold(app.UIAxes2, 'on' );
            end
            hold(app.UIAxes2, 'off' );
            
            %ROC CURVES TEST
            for i=1:k
                X=roc_test{i,1};
                Y=roc_test{i,2};
                plot(app.UIAxes3,X,Y)
                app.UIAxes3.Title.String='TEST ROC';
                app.UIAxes3.XLabel.String='FPR';
                app.UIAxes3.YLabel.String='TPR'
                refline(app.UIAxes3,1,0);
                %hold on
                hold(app.UIAxes3, 'on' );
            end
            hold(app.UIAxes3, 'off' );
            % -------------------------------FUNZIONI---------------------------------
            %----------PCA
            function [x_train_new,x_test_new]=pca_fun(x_train,x_test,k)
                if app.VariancePCAEditField.Value == 0
                    var_p=99;
                else
                    var_p = app.VariancePCAEditField.Value;
                end
               
                x_mean = mean(x_train);
                x_centered = x_train - x_mean;
                warning('off')
                [coeff,score,latent,tsquared,explained,mu] = pca(x_centered,'Centered',false);
                warning('on')
                x_train_PCA = x_centered*coeff;
                n_comp = numel(explained);
                for ii = 1:n_comp
                    % Calcolo la somma dei primi "ii" elementi di explained
                    sum_ = sum(explained(1:ii));
                    if sum_ > var_p, break; end
                end
                num_pc = ii;
                if k==1
                    app.Label_2.Text=[app.Label_2.Text,sprintf('\nTo preserve %.2f%s of variance, PCA reduces genes down to %d PC', var_p, '%', num_pc)];
                end
                x_train_PCA_r = x_train_PCA(:,1:num_pc);
                
                x_test_centered = x_test - x_mean;
                x_test_PCA = x_test_centered*coeff;
                x_test_PCA_r = x_test_PCA(:,1:num_pc);
                
                % Dataset after PCA
                x_train_new = x_train_PCA_r';
                x_test_new = x_test_PCA_r';
                
            end
            
            %--------------LDA
            function [x_train_new,x_test_new]=lda_fun(x_train,x_test,t_train,k)
                
                Mdl = fitcdiscr(x_train,t_train);
                weigths = abs(Mdl.Coeffs(2,1).Linear);                 
                %numel(indexes_selected)
                if app.ThresholdWeightEditField.Value ~= 0
                    threshold_weight=app.ThresholdWeightEditField.Value;
                else
                    if selectedButton == app.ANNButton
                        threshold_weight = 0.001;
                    elseif selectedButton == app.LogisticRegressionButton
                        threshold_weight = 0.00001;
                    else
                        threshold_weight = 0.001;
                    end
                end
                weights_thresholded = weigths(weigths > threshold_weight);
                if k==1
                    app.Label_2.Text=[app.Label_2.Text,sprintf('\nLDA reduces features down to %d',numel(weights_thresholded))];
                end
                
                feature_mask = weigths > threshold_weight;
                
                x_train_reduced_lda = x_train(:, feature_mask);
                x_test_reduced_lda = x_test(:,feature_mask);
                
                x_train_new=x_train_reduced_lda;
                x_test_new=x_test_reduced_lda;
            end
            
            %----------OVER-SAMPLING(SMOTE)
            function [x_os,t_os]=oversampling(x,t,do_all)
                % APPLYING OVERSAMPLING ON TRAIN SET/ALL SET
                %controlliamo la distribuzione low/high
                if do_all
                    set='Data';
                else
                    set='Train ';
                end
                nHigh=0; nLow=0;
                labels_low = zeros(size(x,1),1);
                labels_high = zeros(size(x,1),1);
                for i = 1:size(x,1)
                    if t(i) == 0
                        nLow=nLow+1;
                        labels_low(i) = 1;
                    else
                        nHigh=nHigh+1;
                        labels_high(i) = 1;
                    end
                end
                app.Label_2.Text=[app.Label_2.Text,sprintf('\n%sset counts %d high risk and %d low risk samples-->\t',set,nHigh,nLow)];
                if abs(nHigh-nLow)<(0.15*size(size(x,1),1))
                    app.Label_2.Text=[app.Label_2.Text,sprintf('\n%sset is balanced ---> no over-sampling needed',set)];
                    x_os=x_train; t_os=t_train;
                else
                    if nHigh>nLow
                        app.Label_2.Text=[app.Label_2.Text,sprintf('\n%sset is unbalaced: over-sampling low risk samples',set)];
                        [x_os,t_os]=SMOTE(x,labels_low,2);
                    else
                        app.Label_2.Text=[app.Label_2.Text,sprintf('\n%sset is unbalaced: over-sampling high risk samples\n',set)];
                        [x_os,t_os]=SMOTE(x,labels_high,2);
                    end
                    nHigh_new=0; nLow_new=0;
                    for i=1:length(t_os)
                        if t_os(i)== 1
                            nHigh_new=nHigh_new+1;
                        else
                            nLow_new=nLow_new+1;
                        end
                    end
                    app.Label_2.Text=[app.Label_2.Text,sprintf('\nAfter over-sampling %sset:\t %d high risk \t %d low risk',set,nHigh_new,nLow_new)];
                end
            end
            
            function[accuracy,precision,recall,fscore]=calcparam(C)
                tn = C(1,1);
                fp = C(1,2);
                fn = C(2,1);
                tp = C(2,2);
                accuracy=(tp+tn)/(tp+tn+fp+fn);
                precision=tp/(tp+fp);
                recall=tp/(tp+fn);
                fscore=(2*tp)/((2*tp)+fn+fp);
                %fprintf('\nAccuracy: %.3f \nPrecision: %.3f \nRecall: %.3f \nFScore: %.3f ',accuracy,precision,recall,fscore)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DatabaseConnectionTab = uitab(app.TabGroup);
            app.DatabaseConnectionTab.Title = 'Database Connection';
            app.ConnectButton = uibutton(app.DatabaseConnectionTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.StatusLabel = uilabel(app.DatabaseConnectionTab);
            app.StatusLabel.Text = 'Status';
            app.Lamp = uilamp(app.DatabaseConnectionTab);
            app.LoginButton = uibutton(app.DatabaseConnectionTab, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.DataAppLabel = uilabel(app.DatabaseConnectionTab);
            app.DataAppLabel.Text = 'Data App';
            app.PasswordEditFieldLabel = uilabel(app.DatabaseConnectionTab);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.DatabaseConnectionTab, 'text');
            app.UsernameEditFieldLabel = uilabel(app.DatabaseConnectionTab);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.DatabaseConnectionTab, 'text');
            app.DatabaseEditFieldLabel = uilabel(app.DatabaseConnectionTab);
            app.DatabaseEditFieldLabel.Text = 'Database';
            app.DatabaseEditField = uieditfield(app.DatabaseConnectionTab, 'text');
            app.ReviewLabel = uilabel(app.DatabaseConnectionTab);
            app.ReviewLabel.Text = 'Review';
            app.GenderAgeTab = uitab(app.TabGroup);
            app.GenderAgeTab.Title = 'Gender/Age';
            app.Switch = uiswitch(app.GenderAgeTab, 'slider');
            app.Switch.Items = {'Gender', 'Age'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @Age_switch, true);
            app.Switch.Value = 'Age';
            app.Label_3 = uilabel(app.GenderAgeTab);
            app.Label_3.Text = '';
            app.UIAxes = uiaxes(app.GenderAgeTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.BinaryClassifiersTab = uitab(app.TabGroup);
            app.BinaryClassifiersTab.Title = 'Binary Classifiers';
            app.BinaryClassifiersLabel = uilabel(app.BinaryClassifiersTab);
            app.BinaryClassifiersLabel.Text = 'Binary Classifiers';
            app.TabGroup2 = uitabgroup(app.BinaryClassifiersTab);
            app.SelectionTab = uitab(app.TabGroup2);
            app.SelectionTab.Title = 'Selection';
            app.ButtonGroup = uibuttongroup(app.SelectionTab);
            app.ANNButton = uiradiobutton(app.ButtonGroup);
            app.ANNButton.Text = 'ANN';
            app.ANNButton.Value = true;
            app.LogisticRegressionButton = uiradiobutton(app.ButtonGroup);
            app.LogisticRegressionButton.Text = 'Logistic Regression';
            app.SVMButton = uiradiobutton(app.ButtonGroup);
            app.SVMButton.Text = 'SVM';
            app.OversamplingButtonGroup = uibuttongroup(app.SelectionTab);
            app.OversamplingButtonGroup.Title = 'Oversampling';
            app.DisabledButton = uitogglebutton(app.OversamplingButtonGroup);
            app.DisabledButton.Text = 'Disabled';
            app.DisabledButton.Value = true;
            app.TrainsetonlyButton = uitogglebutton(app.OversamplingButtonGroup);
            app.TrainsetonlyButton.Text = 'Train set only';
            app.WholesetButton = uitogglebutton(app.OversamplingButtonGroup);
            app.WholesetButton.Text = 'Whole set';
            app.FeaturesSelectionButtonGroup = uibuttongroup(app.SelectionTab);
            app.FeaturesSelectionButtonGroup.Title = 'Features Selection';
            app.DisabledButton_2 = uitogglebutton(app.FeaturesSelectionButtonGroup);
            app.DisabledButton_2.Text = 'Disabled';
            app.DisabledButton_2.Value = true;
            app.BenjaminiHochbergButton = uitogglebutton(app.FeaturesSelectionButtonGroup);
            app.BenjaminiHochbergButton.Text = 'Benjamini-Hochberg';
            app.BonferroniButton = uitogglebutton(app.FeaturesSelectionButtonGroup);
            app.BonferroniButton.Text = 'Bonferroni';
            app.FeaturesReductionButtonGroup = uibuttongroup(app.SelectionTab);
            app.FeaturesReductionButtonGroup.Title = 'Features Reduction';
            app.DisabledButton_3 = uitogglebutton(app.FeaturesReductionButtonGroup);
            app.DisabledButton_3.Text = 'Disabled';
            app.DisabledButton_3.Value = true;
            app.LDAButton = uitogglebutton(app.FeaturesReductionButtonGroup);
            app.LDAButton.Text = 'LDA';
            app.PCAButton = uitogglebutton(app.FeaturesReductionButtonGroup);
            app.PCAButton.Text = 'PCA';
            app.LDAPCAButton = uitogglebutton(app.FeaturesReductionButtonGroup);
            app.LDAPCAButton.Text = 'LDA&PCA';
            app.GOButton = uibutton(app.SelectionTab, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.PathLabel = uilabel(app.SelectionTab);
            app.PathLabel.Text = '   Path:';
            app.Label = uilabel(app.SelectionTab);
            app.Label.Text = '';
            app.VariancePCAEditFieldLabel = uilabel(app.SelectionTab);
            app.VariancePCAEditFieldLabel.Text = 'Variance PCA';
            app.VariancePCAEditField = uieditfield(app.SelectionTab, 'numeric');
            app.ThresholdWeightEditFieldLabel = uilabel(app.SelectionTab);
            app.ThresholdWeightEditFieldLabel.Text = 'Threshold Weight';
            app.ThresholdWeightEditField = uieditfield(app.SelectionTab, 'numeric');
            app.ResultsTab = uitab(app.TabGroup2);
            app.ResultsTab.Title = 'Results';
            app.Label_2 = uilabel(app.ResultsTab);
            app.Label_2.Text = '';
            app.ROCCurveTab = uitab(app.TabGroup2);
            app.ROCCurveTab.Title = 'ROC Curve';
            app.UIAxes2 = uiaxes(app.ROCCurveTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.ROCCurveTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BreastCancer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end